classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PulsePanel                    matlab.ui.container.Panel
        periodEditFieldLabel          matlab.ui.control.Label
        periodEditField               matlab.ui.control.NumericEditField
        dutycycleEditFieldLabel       matlab.ui.control.Label
        dutycycleEditField            matlab.ui.control.NumericEditField
        usLabel                       matlab.ui.control.Label
        signal1Panel                  matlab.ui.container.Panel
        fEditField_2Label             matlab.ui.control.Label
        fEditField_2                  matlab.ui.control.NumericEditField
        delayEditField_2Label         matlab.ui.control.Label
        delayEditField_2              matlab.ui.control.NumericEditField
        signallengthEditField_3Label  matlab.ui.control.Label
        signallengthEditField_3       matlab.ui.control.NumericEditField
        starttimeEditFieldLabel       matlab.ui.control.Label
        starttimeEditField            matlab.ui.control.NumericEditField
        usLabel_2                     matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        msLabel_3                     matlab.ui.control.Label
        kHzLabel                      matlab.ui.control.Label
        signal2Panel                  matlab.ui.container.Panel
        fEditField_3Label             matlab.ui.control.Label
        fEditField_3                  matlab.ui.control.NumericEditField
        delayEditField_3Label         matlab.ui.control.Label
        delayEditField_3              matlab.ui.control.NumericEditField
        signallengthEditField_4Label  matlab.ui.control.Label
        signallengthEditField_4       matlab.ui.control.NumericEditField
        starttimeEditField_2Label     matlab.ui.control.Label
        starttimeEditField_2          matlab.ui.control.NumericEditField
        usLabel_3                     matlab.ui.control.Label
        msLabel_2                     matlab.ui.control.Label
        msLabel_4                     matlab.ui.control.Label
        kHzLabel_2                    matlab.ui.control.Label
        AcquisitionCardPanel          matlab.ui.container.Panel
        samplerateEditFieldLabel      matlab.ui.control.Label
        samplerateEditField           matlab.ui.control.NumericEditField
        samplenumberEditFieldLabel    matlab.ui.control.Label
        samplenumberEditField         matlab.ui.control.NumericEditField
        MHzLabel                      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        plotButton                    matlab.ui.control.Button
        plotButton_2                  matlab.ui.control.Button
        recordButton                  matlab.ui.control.Button
        locationButton                matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        delta_t % Description
        tt
        t
        signal1
        signal2
        modulation
        modulatedSignal1
        modulatedSignal2
        pulsePeriod
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.delta_t=1/app.samplerateEditField.Value*1e-6;
            num=app.samplenumberEditField.Value;
            app.t=app.delta_t:app.delta_t:num*app.delta_t;
            GaussinCenter1=app.starttimeEditField.Value*1e-3;
            GaussinCenter2=app.starttimeEditField_2.Value*1e-3;
            alpha1=app.signallengthEditField_3.Value*1e-3;
            alpha2=app.signallengthEditField_4.Value*1e-3;
            GaussinWindow1=exp(-(app.t-GaussinCenter1).^2./alpha1^2);
            GaussinWindow2=exp(-(app.t-GaussinCenter2).^2./alpha2^2);
            delay1=app.delayEditField_2.Value*1e-6;
            delay2=app.delayEditField_3.Value*1e-6;
            delayRemain=10*max(delay1,delay2);
            delayRemain=(delayRemain/app.delta_t);
            app.tt=app.delta_t:app.delta_t:(num+delayRemain)*app.delta_t;
            f1=app.fEditField_2.Value*1e3;
            f2=app.fEditField_3.Value*1e3;
            intrusion1=sin(0.5*sin(2*pi*f1*app.t));
            intrusion2=sin(0.5*sin(2*pi*f2*app.t));
            y11=[intrusion1.*GaussinWindow1,zeros(1,int32(delayRemain))];
            y12=[zeros(1,int32(abs(delay1)/app.delta_t)),intrusion1.*GaussinWindow1,zeros(1,int32(delayRemain)-int32(abs(delay1)/app.delta_t))];%互相关时间差为0-10us，即0-10delta_t
            y21=[intrusion2.*GaussinWindow2,zeros(1,int32(delayRemain))];
            y22=[zeros(1,int32(abs(delay2)/app.delta_t)),intrusion2.*GaussinWindow2,zeros(1,int32(delayRemain)-int32(abs(delay2)/app.delta_t))];%互相关时间差为0-10us，即0-10delta_t
            if delay1>0&&delay2>0
            app.signal1=y11+y21;
            app.signal2=y12+y22;
            end
            if delay1>0&&delay2<0
            app.signal1=y11+y22;
            app.signal2=y12+y21;
            end
            if delay1<0&&delay2>0
            app.signal1=y12+y21;
            app.signal2=y11+y22;
            end
            if delay1<0&&delay2<0
            app.signal2=y11+y21;
            app.signal1=y12+y22;
            end
            plot(app.UIAxes,app.tt*1e3,app.signal1,app.tt*1e3,app.signal2)           
            
        end

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
          period=app.periodEditField.Value*1e-6;
          app.pulsePeriod=period;
          pulseLength=period.*app.dutycycleEditField.Value; 
          time=app.tt;
          app.modulation=pulse(pulseLength,time,period);
          app.modulatedSignal1=app.signal1.*app.modulation;
          app.modulatedSignal2=app.signal2.*app.modulation;
          plot(app.UIAxes_2,app.tt*1e3,app.modulatedSignal1,app.tt*1e3,app.modulatedSignal2)
      function result=pulse(pulseLength,time,period)
result=zeros(size(time));
for i=time
    shadow=mod(i,period);
    if shadow<pulseLength
        result((time==i))=1;
    else
        result((time==i))=0;
    end
end
end          
        end

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
                period=app.periodEditField.Value*1e-6;
                n=period/app.delta_t;
                m=floor(app.samplenumberEditField.Value*app.delta_t/period);
                partT=app.delta_t:app.delta_t:n*app.delta_t;
                partTcorr=-(n-1)*app.delta_t:app.delta_t:(n-1)*app.delta_t;
                n=int32(n);
                A1=reshape(app.modulatedSignal1(1:m*n),n,m);
                A2=reshape(app.modulatedSignal2(1:m*n),n,m);
                recordTime=period:period:m*period;
                record=100e-6*zeros(1,m);
                for i=1:m
                %     figure(4)
                    part1=xcorr(A1(:,i),A2(:,i));
                %     plot(partTcorr,part1)
                    value=max(part1);
                    amplitude=max(max(A1(:,i)),max(A2(:,i)));
                    result=partTcorr((part1==value));
                    result=result(1);
                    if amplitude>0.05
                    record(i)=result;
                    end
                end
                stem(app.UIAxes_3,recordTime*1e3,record*1e6)
                histogram(app.UIAxes_4,record*1e6,100)
        end

        % Button pushed function: locationButton
        function locationButtonPushed(app, event)
        [~,n]=size(app.signal1);
        period_n=app.pulsePeriod/app.delta_t;
        partTcorr=-(period_n-1)*app.delta_t:app.delta_t:(period_n-1)*app.delta_t;
        newrecord=100e-6*ones(1,n-period_n-1);
        period_n=int32(period_n);
        for i=1:n-period_n-1
            part1=app.signal1(i:i+period_n-1);
            part2=app.signal2(i:i+period_n-1);
            result=xcorr(part1,part2);
            value=max(result);
            delay=partTcorr(result==value);
            delay=delay(1);
            newrecord(i)=delay;
        end
        range=20;
        chart=-range*app.delta_t:0.2*app.delta_t:range*app.delta_t-0.2*app.delta_t;
        table=zeros(1,range*10);
        [~,n]=size(newrecord);
        for i=1:1:10*range
    count=0;
    for j=1:n
        if int16(newrecord(j)*1e6)==int16(chart(i)*1e6)
            count=count+1;
        end
    end
    table(i)=count;
        end
        [~,Table]=meshgrid(1:5,table);
        mesh(app.UIAxes2,1:5,chart*1e6,Table)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PulsePanel = uipanel(app.UIFigure);
            app.PulsePanel.Title = 'Pulse';
            app.periodEditFieldLabel = uilabel(app.PulsePanel);
            app.periodEditFieldLabel.Text = 'period';
            app.periodEditField = uieditfield(app.PulsePanel, 'numeric');
            app.periodEditField.Value = 200;
            app.dutycycleEditFieldLabel = uilabel(app.PulsePanel);
            app.dutycycleEditFieldLabel.Text = 'duty cycle';
            app.dutycycleEditField = uieditfield(app.PulsePanel, 'numeric');
            app.dutycycleEditField.Value = 0.5;
            app.usLabel = uilabel(app.PulsePanel);
            app.usLabel.Text = 'us';
            app.signal1Panel = uipanel(app.UIFigure);
            app.signal1Panel.Title = 'signal1';
            app.fEditField_2Label = uilabel(app.signal1Panel);
            app.fEditField_2Label.Text = 'f';
            app.fEditField_2 = uieditfield(app.signal1Panel, 'numeric');
            app.fEditField_2.Value = 20;
            app.delayEditField_2Label = uilabel(app.signal1Panel);
            app.delayEditField_2Label.Text = 'delay';
            app.delayEditField_2 = uieditfield(app.signal1Panel, 'numeric');
            app.delayEditField_2.Value = 5;
            app.signallengthEditField_3Label = uilabel(app.signal1Panel);
            app.signallengthEditField_3Label.Text = 'signal length';
            app.signallengthEditField_3 = uieditfield(app.signal1Panel, 'numeric');
            app.signallengthEditField_3.Value = 1.5;
            app.starttimeEditFieldLabel = uilabel(app.signal1Panel);
            app.starttimeEditFieldLabel.Text = 'start time';
            app.starttimeEditField = uieditfield(app.signal1Panel, 'numeric');
            app.starttimeEditField.Value = 1.5;
            app.usLabel_2 = uilabel(app.signal1Panel);
            app.usLabel_2.Text = 'us';
            app.msLabel = uilabel(app.signal1Panel);
            app.msLabel.Text = 'ms';
            app.msLabel_3 = uilabel(app.signal1Panel);
            app.msLabel_3.Text = 'ms';
            app.kHzLabel = uilabel(app.signal1Panel);
            app.kHzLabel.Text = 'kHz';
            app.signal2Panel = uipanel(app.UIFigure);
            app.signal2Panel.Title = 'signal2';
            app.fEditField_3Label = uilabel(app.signal2Panel);
            app.fEditField_3Label.Text = 'f';
            app.fEditField_3 = uieditfield(app.signal2Panel, 'numeric');
            app.fEditField_3.Value = 20;
            app.delayEditField_3Label = uilabel(app.signal2Panel);
            app.delayEditField_3Label.Text = 'delay';
            app.delayEditField_3 = uieditfield(app.signal2Panel, 'numeric');
            app.delayEditField_3.Value = 5;
            app.signallengthEditField_4Label = uilabel(app.signal2Panel);
            app.signallengthEditField_4Label.Text = 'signal length';
            app.signallengthEditField_4 = uieditfield(app.signal2Panel, 'numeric');
            app.signallengthEditField_4.Value = 1.5;
            app.starttimeEditField_2Label = uilabel(app.signal2Panel);
            app.starttimeEditField_2Label.Text = 'start time';
            app.starttimeEditField_2 = uieditfield(app.signal2Panel, 'numeric');
            app.starttimeEditField_2.Value = 5;
            app.usLabel_3 = uilabel(app.signal2Panel);
            app.usLabel_3.Text = 'us';
            app.msLabel_2 = uilabel(app.signal2Panel);
            app.msLabel_2.Text = 'ms';
            app.msLabel_4 = uilabel(app.signal2Panel);
            app.msLabel_4.Text = 'ms';
            app.kHzLabel_2 = uilabel(app.signal2Panel);
            app.kHzLabel_2.Text = 'kHz';
            app.AcquisitionCardPanel = uipanel(app.UIFigure);
            app.AcquisitionCardPanel.Title = 'Acquisition Card';
            app.samplerateEditFieldLabel = uilabel(app.AcquisitionCardPanel);
            app.samplerateEditFieldLabel.Text = 'sample rate';
            app.samplerateEditField = uieditfield(app.AcquisitionCardPanel, 'numeric');
            app.samplerateEditField.Value = 1;
            app.samplenumberEditFieldLabel = uilabel(app.AcquisitionCardPanel);
            app.samplenumberEditFieldLabel.Text = 'sample number';
            app.samplenumberEditField = uieditfield(app.AcquisitionCardPanel, 'numeric');
            app.samplenumberEditField.Value = 8000;
            app.MHzLabel = uilabel(app.AcquisitionCardPanel);
            app.MHzLabel.Text = 'MHz';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'signal')
            xlabel(app.UIAxes, 'time/ms')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'modulated signal')
            xlabel(app.UIAxes_2, 'time/ms')
            ylabel(app.UIAxes_2, 'amplitude')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'record')
            xlabel(app.UIAxes_3, 'time/ms')
            ylabel(app.UIAxes_3, 'delay/us')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'record analysis')
            xlabel(app.UIAxes_4, 'time/us')
            ylabel(app.UIAxes_4, 'number')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.plotButton_2 = uibutton(app.UIFigure, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.recordButton = uibutton(app.UIFigure, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'record';
            app.locationButton = uibutton(app.UIFigure, 'push');
            app.locationButton.ButtonPushedFcn = createCallbackFcn(app, @locationButtonPushed, true);
            app.locationButton.Text = 'location';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'disturbance visualization')
            xlabel(app.UIAxes2, 'fiber')
            ylabel(app.UIAxes2, 'delay/us')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end